classdef corr2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SASE_statistic          matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        correlationButton       matlab.ui.control.Button
        shotsEditFieldLabel     matlab.ui.control.Label
        shotsEditField          matlab.ui.control.NumericEditField
        yEditField              matlab.ui.control.EditField
        x1EditFieldLabel        matlab.ui.control.Label
        x1EditField             matlab.ui.control.EditField
        x2EditFieldLabel        matlab.ui.control.Label
        x2EditField             matlab.ui.control.EditField
        x3EditFieldLabel        matlab.ui.control.Label
        x3EditField             matlab.ui.control.EditField
        x4EditFieldLabel        matlab.ui.control.Label
        x4EditField             matlab.ui.control.EditField
        SASE1CheckBox           matlab.ui.control.CheckBox
        SASE2CheckBox           matlab.ui.control.CheckBox
        SASE3CheckBox           matlab.ui.control.CheckBox
        yCheckBox               matlab.ui.control.CheckBox
        RightPanel              matlab.ui.container.Panel
        UIAxes1                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes6                 matlab.ui.control.UIAxes
        analysingTextAreaLabel  matlab.ui.control.Label
        analysingTextArea       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SASE_statistic.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {838, 838};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {356, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: correlationButton
        function correlationButtonPushed(app, event)
         if app.SASE1CheckBox.Value
             yaddress='XFEL.FEL/XGM/XGM.2643.T9/INTENSITY.SA1.RAW.TRAIN';
         
             lam=doocsread('XFEL.FEL/XGM.PHOTONFLUX/XGM.2643.T9/WAVELENGTH');
             lambda=lam.data;
             SASE='SASE1';
         end
         if app.SASE2CheckBox.Value
         yaddress='XFEL.FEL/XGM/XGM.2595.T6/INTENSITY.RAW.TRAIN';
          SASE='SASE2';
          %HIREX='XFEL.UTIL/HIREX.ML/SASE2/HIREX.DATA'
          lam=doocsread('XFEL.FEL/XGM.PHOTONFLUX/XGM.2595.T6/WAVELENGTH');
          lambda=lam.data;
          %y=doocsread('XFEL.UTIL/DYNPROP/MISC/HIREX_AMPL')
         
         end
         
         if app.SASE3CheckBox.Value
             yaddress='XFEL.FEL/XGM/XGM.3130.T10/INTENSITY.SA3.RAW.TRAIN';
             lam=doocsread('XFEL.FEL/XGM.PHOTONFLUX/XGM.3130.T10/WAVELENGTH');
             lambda=lam.data;
              SASE='SASE3';
         end
          if app.yCheckBox.Value
              yaddress=app.yEditField.Value;
              lambda=nan;
          end
         shots=app.shotsEditField.Value;
         x1address=app.x1EditField.Value;
         x2address=app.x2EditField.Value;
         x3address=app.x3EditField.Value;
         x4address=app.x4EditField.Value;
        f=waitbar(0.1, 'processing');
            for i=1: shots        
            dy=doocsread(yaddress);
            dx1=doocsread(x1address);
            dx2=doocsread(x2address);
            dx3=doocsread(x3address);
           dx4=doocsread(x4address);
            y(i)=dy.data;
            x1(i)=dx1.data;
            x2(i)=dx2.data;
            x3(i)=dx3.data;
            x4(i)=dx4.data;
            tm=0.105;
            pause(tm);
%            
            if (i==shots/2)
                close(f);
              f=waitbar(0.5, 'processing')  ;
            end
            end
            close(f);
           % app.lable='Done';
            plot(app.UIAxes1, x1, y,'bo', 'MarkerFaceColor','b') ;
            %legend(app.UIAxes1,{'Principal','Interest'},'Location','Best')
            %xlim(app.UIAxes1,[0 nper]) ; 
            
            plot(app.UIAxes2, x2, y,'ro','MarkerFaceColor','r') ;
            plot(app.UIAxes3, x3, y,'mo','MarkerFaceColor','m') ;
            plot(app.UIAxes4, x4, y,'ko','MarkerFaceColor','k') ;
             
             plot(app.UIAxes5, (1:shots)*tm, y,'bo', 'MarkerFaceColor','b') ;
           %  xlable(app.UIAxes5,'t(s)')
            % histcounts(y)
            binrange=mean(y)-mean(y)/4:mean(y)/40:mean(y)+mean(y)/4;
            bincou=histc(y,binrange);
            bar(app.UIAxes6, binrange,bincou);
            
            time=datestr(now,'yyyy_mm_dd_HH_MM')

            filename=sprintf('%s_%s.mat', SASE, time);
            
            
            text0=[SASE];
            text01=['ph wavelength(nm)=', num2str(lambda)];
            text1=['rms=',  num2str(rms(y)) ] ;
            text2=[ 'std=',  num2str(std(y)) ];
            text3=['mean=', num2str(mean(y))];
            text4=['stability =', num2str((1-(std(y)/mean(y)) )*100), '%'];
            app.analysingTextArea.FontSize=22;
            app.analysingTextArea.Value={text0;text01;text1;text2; text3; text4; filename};
            
            %%%%%%%%%
            data_SASE=struct;
            data_SASE.SASEIntensity=y;
            data_SASE.x1=x1;
            data_SASE.x2=x2;
            data_SASE.x3=x3;
            data_SASE.x4=x4;
            data_SASE.SASELine=SASE;
            data_SASE.result={text0;text01;text1;text2; text3; text4};
            data.SASE.shotnumber=shots;
            data.SASE.wavelength=lambda;
clc


           cd  /System/Volumes/Data/home/xfeloper/data/SASE_statistic/
           save(filename, 'data_SASE')
          % print('/System/Volumes/Data/home/xfeloper/data/SASE_statistic/')
          %filname
          fprintf('/System/Volumes/Data/home/xfeloper/data/SASE_statistic/"%s".\n',filename)
          
          txt=sprintf('/System/Volumes/Data/home/xfeloper/data/SASE_statistic/%s.\n',filename);
            % sending to logbook 

            figure(12234234);
            subplot(1,2,1);
            plot((1:shots)*tm, y,'bo', 'MarkerFaceColor','b') ;
            xlabel('t(s)', 'FontSize', 15);
            ylabel(SASE);
            subplot(1,2,2);
            bar( binrange,bincou);
            xlabel('pulse energy', 'FontSize', 15);
            ylabel('n', 'FontSize', 15);
            camroll(-90)
            
            text=[txt '\r\n' ...
                text0 '\r\n' ...
                text1 '\r\n' ...
                text2 '\r\n' ...
                text3 '\r\n' ...
                text4 '\r\n' ...
                txt '\r\n' ]
%             text=['mean energy spread(MeV)=', num2str(mean(sigma)) '\r\n', ...
%              'rms energy spread(MeV)=', num2str(RMS_Y), '\r\n', ...
%              'data saved in:', filename]         
            
            
 A=getframe(gcf)
 imwrite(A.cdata, 'temporly.png');
result_log=hlc_send_to_logbook('author', 'Mirian','title', 'SASE statistic ', ...
    'text', sprintf(text),...
     'image', 'temporly.png')
% 
% delete('temporly.png')
%             fig=figure;
%             copyobj(app.RightPanel, fig)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SASE_statistic = uifigure('Visible', 'off');
            app.SASE_statistic.Name = 'SASE statistic ';
            app.SASE_statistic.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SASE_statistic);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'SASE1 SAE2 SASE3';
            app.correlationButton = uibutton(app.LeftPanel, 'push');
            app.correlationButton.ButtonPushedFcn = createCallbackFcn(app, @correlationButtonPushed, true);
            app.correlationButton.Text = 'correlation';
            app.shotsEditFieldLabel = uilabel(app.LeftPanel);
            app.shotsEditFieldLabel.Text = 'shots';
            app.shotsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.shotsEditField.Value = 500;
            app.yEditField = uieditfield(app.LeftPanel, 'text');
            app.yEditField.Value = 'XFEL.FEL/XGM/XGM.2643.T9/INTENSITY.SA1.RAW.TRAIN';
            app.x1EditFieldLabel = uilabel(app.LeftPanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.LeftPanel, 'text');
            app.x1EditField.Value = 'XFEL.FEEDBACK/FT2.LONGITUDINAL/MONITOR4/MEAN';
            app.x2EditFieldLabel = uilabel(app.LeftPanel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.LeftPanel, 'text');
            app.x2EditField.Value = 'XFEL.FEEDBACK/FT2.LONGITUDINAL/MONITOR8/MEAN';
            app.x3EditFieldLabel = uilabel(app.LeftPanel);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.LeftPanel, 'text');
            app.x3EditField.Value = 'XFEL.FEEDBACK/FT2.LONGITUDINAL/MONITOR13/MEAN';
            app.x4EditFieldLabel = uilabel(app.LeftPanel);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.LeftPanel, 'text');
            app.x4EditField.Value = 'XFEL.FEEDBACK/FT2.LONGITUDINAL/MONITOR14/MEAN';
            app.SASE1CheckBox = uicheckbox(app.LeftPanel);
            app.SASE1CheckBox.Text = 'SASE1';
            app.SASE2CheckBox = uicheckbox(app.LeftPanel);
            app.SASE2CheckBox.Text = 'SASE2';
            app.SASE3CheckBox = uicheckbox(app.LeftPanel);
            app.SASE3CheckBox.Text = 'SASE3';
            app.yCheckBox = uicheckbox(app.LeftPanel);
            app.yCheckBox.Text = 'y';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'plots';
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, '1')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'SASE energy')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'SASE energy')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, '3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'SASE energy')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, '4')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'SASE energy')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Real time aquisition ')
            xlabel(app.UIAxes5, 't(s)')
            ylabel(app.UIAxes5, 'SASE en')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Histogram ')
            xlabel(app.UIAxes6, 'pulse senergy ')
            ylabel(app.UIAxes6, 'n')
            app.analysingTextAreaLabel = uilabel(app.RightPanel);
            app.analysingTextAreaLabel.Text = 'analysing ';
            app.analysingTextArea = uitextarea(app.RightPanel);
            app.analysingTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SASE_statistic.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = corr2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SASE_statistic)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SASE_statistic)
        end
    end
end